classdef pitchMark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        glMain                matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        glToolbar             matlab.ui.container.GridLayout
        btnZoomAll            matlab.ui.control.Button
        btnZoomSel            matlab.ui.control.Button
        glStatusbar           matlab.ui.container.GridLayout
        CursorEditFieldLabel  matlab.ui.control.Label
        txCursor              matlab.ui.control.EditField
        glWavebar             matlab.ui.container.GridLayout
        axWavebar             matlab.ui.control.UIAxes
        btnWavebarDec         matlab.ui.control.Button
        btnWavebarInc         matlab.ui.control.Button
        SliderLabel           matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
    end

    properties (Access = private)
      % General                                    % -------------------
      DEBUG   = true;                              % Debug flag
      name    = 'Pitch Mark App';                  % App name
      
      % App state                                  % -------------------
      wave;                                        % Current wave data
      rid;                                         % Recording ID
      kDrag   = -1;                                % Initial mouse drag sample index
      sel;                                         % Plot selection

      % Folders                                    % -------------------
      dirHome = '../';                             % vVZ home 
      dirWav  = 'waves/';                          % relative to dirHome
      dirLab  = 'labels/';                         % relative to dirHome

      % Plots                                      % -------------------
      zoom;
    end

    % UI utilities
    methods (Access = private)
        
      function updateUI(app)
        if isempty(app.wave)
          app.UIFigure.Name = app.name;
          app.btnZoomSel.Enable = false;
          app.btnZoomAll.Enable = false;
          app.Slider.Enable = false;
          app.txCursor.Value = '(n/a)';
          app.updateWavebar();
          return;
        end
        K = length(app.wave);
        app.UIFigure.Name = [app.name ' - ' app.rid];
        s = app.getSelection();
        app.btnZoomSel.Enable = ~isempty(s);
        xl = app.UIAxes.XLim;
        if xl(1)>0 || xl(2)<K-1  % Zoom-in state
          app.btnZoomAll.Enable = true;
          app.Slider.Enable = true;
          l = K-1-(xl(2)-xl(1));
          app.Slider.Value = xl(1)/l;
        else % Zoom-all state
          app.btnZoomAll.Enable = false;
          app.Slider.Enable = false;
          app.Slider.Value = 0;
        end
        app.updateWavebar();
      end
        
      function updateWavebar(app)
        if ~isempty(app.wave)
          yl = app.axWavebar.YLim;
          xl = app.UIAxes.XLim;
          if ~isempty(app.zoom)
            delete(app.zoom);
            app.zoom = zeros(0);
          end
          dim = [xl(1) yl(1) xl(2)-xl(1) yl(2)-yl(1)];
          app.zoom = rectangle(app.axWavebar,'Position',dim, ...
            'FaceColor',[0 0 0 0.2],'EdgeColor','none');
          app.axWavebar.YLim = yl;
        else
        end
      end
  
      function addToRecMenu(app,rid)
        r = strsplit(rid,'/');
        m = app.OpenMenu;
        for i=1:length(r)
          m = app.addSubMenu(m,r{i});
          if i==length(r)
            m.UserData = rid;          
          end
        end
      end
  
      function sm = addSubMenu(app,m,name)
        sm = 0;
        for i=1:length(m.Children)
          cld = m.Children(i);
          if strcmp(cld.Text,name)
            sm = cld;
          end
        end
        if sm==0
          sm = uimenu(m);
          sm.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
          sm.Text = name;
        end
      end

      function b = inPlot(app)
        b  = false;
        cp = app.UIFigure.CurrentPoint;
        ap = app.UIAxes.InnerPosition;
        if cp(1)<ap(1) || cp(1)>ap(1)+ap(3) || ... % out of horizonal limits
           cp(2)<ap(2) || cp(2)>ap(2)+ap(4)        % out of vertical limits
          return;
        end
        b = true;
      end
  
      function setSelection(app,k0,k1)
        if ~isempty(app.wave)
          K  = length(app.wave);
          yl = app.UIAxes.YLim;
          if ~isempty(app.sel)
            delete(app.sel); 
            app.sel = zeros(0);
          end
          if k0>=0 && k1>=0
            if k0>k1; k=k1; k1=k0; k0=k; end
            if k1>=K; k1=K-1; end
            dim = [k0 yl(1) k1-k0 yl(2)-yl(1)];
            app.sel = rectangle(app.UIAxes,'Position',dim, ...
              'FaceColor',[0 0.5 1 0.1],'EdgeColor','none');
            app.UIAxes.YLim = yl;
          end
        end
        app.updateUI();
      end
  
      function s = getSelection(app)
        s = zeros(0);
        if ~isempty(app.sel)
          s(1) = app.sel.Position(1);
          s(2) = s(1) + app.sel.Position(3);
        end
      end
  
      function clearSelection(app)
        app.setSelection(-1,-1);
      end
  
      function zoomTo(app,k0,k1)
        if isempty(app.wave); return; end
        K = length(app.wave);
        if k0>k1; k=k1; k1=k0; k0=k; end
        if k0< 0; k0 = 0;   end
        if k1>=K; k1 = K-1; end
        app.UIAxes.XLim = [k0 k1];
        app.updateUI();
      end
  
    end
    
    % File utilities
    methods (Access = private)
    
      function loadWave(app,rid)
        app.clearSelection();
        cla(app.UIAxes,'reset');
        fn = strrep([app.dirWav '/' rid '.wav'],'\','/');
        fprintf('LOAD WAVE\n:- \"%s\"\n',fn);
        app.wave = audioread(fn);
        app.rid  = rid;
        K = length(app.wave);
        fprintf('- %d samples\n',K);
        plot(app.UIAxes,0:K-1,app.wave,'Color','black');
        app.UIAxes.XLim = [0,K-1];
        plot(app.axWavebar,0:K-1,app.wave,'Color','black');
        app.axWavebar.XLim = [0,K-1];
        app.updateUI();
      end
  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % DEBUGGIN...
          if app.DEBUG
            close all; clc;
          end
            
          % Normalize folder names
          l = dir([app.dirHome app.dirWav]);
          app.dirWav = l(1).folder;
          l = dir([app.dirHome app.dirLab]);
          app.dirLab = l(1).folder;
          
          % Search wave files and build recordings list
          l = dir([app.dirWav '/**/*.wav']);
          for i=1:length(l)
            rec = strrep(l(i).folder,app.dirWav,'');
            rec = strrep(rec,'\','/');
            rec = [ rec(2:length(rec)) '/' strrep(l(i).name,'.wav','')];
            app.addToRecMenu(rec);
          end
          
          % Update UI state
          app.axWavebar.Visible = 'off';
          app.updateUI();
        end

        % Callback function: UIFigure, UIFigure, UIFigure
        function UIFigureWindowButton(app, event)
          k  = round(app.UIAxes.CurrentPoint(1,1));
          xl = app.UIAxes.XLim;
          switch event.EventName
            case 'WindowMousePress'
              if app.inPlot()
                app.kDrag = k;
                app.setSelection(k,k);
                fprintf('MOUSE DOWN: k=%g [%g,%g]\n',k,xl(1),xl(2));
              end
            case 'WindowMouseRelease'
              if app.inPlot()
                if app.kDrag>=0
                  app.setSelection(k,app.kDrag);
                end
                fprintf('MOUSE UP  : k=%g [%g,%g]\n',k,xl(1),xl(2));
              else
                fprintf('MOUSE UP  : (outside plot)\n');
              end
              app.kDrag = -1;
            case 'WindowMouseMotion'
              if app.inPlot() && ~isempty(app.wave)
                K = length(app.wave);
                if k< 0; k=0;   end
                if k>=K; k=K-1; end
                s = sprintf('k=%d, x(k)=%4.3f',k,app.wave(k+1));
                app.txCursor.Value = s;
                if app.kDrag>=0
                  app.setSelection(k,app.kDrag);
                  fprintf('MOUSE DRAG: k=%g [%g,%g]\n',k,xl(1),xl(2));
                end
              else
                app.txCursor.Value = '(n/a)';
              end
          end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
          if ~isempty(event.Source.UserData)
            app.loadWave(event.Source.UserData);
          end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
          close(app.UIFigure);
        end

        % Callback function: Slider, Slider
        function ScrollSliderValue(app, event)
          if ~isempty(app.wave)
            v  = event.Value;
            K  = length(app.wave);
            xl = app.UIAxes.XLim;
            l  = K-1-(xl(2)-xl(1));
            app.UIAxes.XLim = [round(v*l) round(v*l)+xl(2)-xl(1)];
          else
          end
          app.updateUI();
        end

        % Button pushed function: btnZoomSel
        function btnZoomSelButtonPushed(app, event)
          if isempty(app.wave); return; end
          s = app.getSelection();
          if ~isempty(s)
            app.zoomTo(s(1),s(2));
            app.clearSelection();
          end
        end

        % Button pushed function: btnZoomAll
        function btnZoomAllButtonPushed(app, event)
          if isempty(app.wave); return; end
          app.zoomTo(0,length(app.wave)-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Name';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.glMain = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.glMain);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.glToolbar = uigridlayout(app.glMain);
            app.btnZoomAll = uibutton(app.glToolbar, 'push');
            app.btnZoomAll.ButtonPushedFcn = createCallbackFcn(app, @btnZoomAllButtonPushed, true);
            app.btnZoomAll.Enable = 'off';
            app.btnZoomAll.Tooltip = {'Zoom all'};
            app.btnZoomAll.Text = 'ZA';
            app.btnZoomSel = uibutton(app.glToolbar, 'push');
            app.btnZoomSel.ButtonPushedFcn = createCallbackFcn(app, @btnZoomSelButtonPushed, true);
            app.btnZoomSel.Enable = 'off';
            app.btnZoomSel.Tooltip = {'Zoom to selection'};
            app.btnZoomSel.Text = 'ZS';
            app.glStatusbar = uigridlayout(app.glMain);
            app.CursorEditFieldLabel = uilabel(app.glStatusbar);
            app.CursorEditFieldLabel.Text = 'Cursor';
            app.txCursor = uieditfield(app.glStatusbar, 'text');
            app.txCursor.Editable = 'off';
            app.glWavebar = uigridlayout(app.glMain);
            app.axWavebar = uiaxes(app.glWavebar);
            title(app.axWavebar, '')
            xlabel(app.axWavebar, '')
            ylabel(app.axWavebar, '')
            app.axWavebar.XTick = [];
            app.axWavebar.YTick = [];
            app.btnWavebarDec = uibutton(app.glWavebar, 'push');
            app.btnWavebarDec.Text = '<';
            app.btnWavebarInc = uibutton(app.glWavebar, 'push');
            app.btnWavebarInc.Text = '>';
            app.SliderLabel = uilabel(app.glWavebar);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.glWavebar);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pitchMark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end